classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        QUERYIMAGELabel             matlab.ui.control.Label
        TOP10SIMILIARIMAGESLabel    matlab.ui.control.Label
        RetriveSimiliarImageButton  matlab.ui.control.Button
        BrowseButton                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_10                   matlab.ui.control.UIAxes
        UIAxes_9                    matlab.ui.control.UIAxes
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Define private properties here
        n = 1000 * 15; % Number of trained images
        X % This should be the data matrix for your trained feature
        fv
        classNames
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.*','Select the Input Color Image');

            % Check if the user canceled the file selection
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('File selection canceled.');
                return;
            end
            filewithpath=strcat(pathname,filename);
            I=imread(filewithpath);
            
            dwtmode('per');
            % Display the image in the UIAxes
            imshow(I, 'Parent', app.UIAxes);


            %-----------Color Feature Vector-------------------
            cld=findcld(I);     %Finding CLD
            cld=cld/max(cld);
            [cofA,~]=dwt(cld,'sym4');   %DWT of CLD
            colfv=cofA; %Approx. Coeff. as color feature vector
            
            %------------Texture Feature Vector----------------
            [C,L]=wavedec2(rgb2gray(I),2,'sym4');   %Wavelet Decomposition
            cA=appcoef2(C,L,'sym4');    %Getting Approximation coeff.
            [cH,cV,cD]=detcoef2('all',C,L,2);   %Getting Detailed coeff.
            F=[cA, cH; cV, cD];
            texfv=findehd(F);   %Finding EHD
            texfv=texfv/max(texfv);
            app.fv=[colfv,texfv];   %Getting total feature vector
        end

        % Button pushed function: RetriveSimiliarImageButton
        function RetriveSimiliarImageButtonPushed(app, event)
            loadedData = load('vege-feature.mat');
                         
            % Store the loaded data in the app property
            app.X = loadedData.X;
            app.classNames = loadedData.classNames;
            %--------------------------------------------------
            distarray=zeros(app.n,1);   %Initialize difference array
            % Check the size of app.X and app.fv
            disp('Size of app.X:');
            disp(size(app.X));
            disp('Size of app.fv:');
            disp(size(app.fv));
            for i=1:app.n
                distarray(i)=sum(abs(app.X(i,:)-app.fv));   %Finding L1 distance
            end
            
            [~,index]=sort(distarray);   %Sorting the difference

            disp('index');
            disp(index);

            % Display the top 10 matched images
            for i = 1:10
                % Adjust the indexing to retrieve the original image names (0001 to 1000)
                if index(i) > 1000
                    temp = mod(index(i), 1000) / 1000;
                    originalIndex = round(temp * 1000);
                else
                    originalIndex = index(i);
                end
        
                originalImageName = sprintf('%04d.jpg', originalIndex);
                
                 % Calculate match percentage
                matchPercentage = 100 - (distarray(index(i)) / max(distarray)) * 100;

                uiAxesComponent = app.(['UIAxes_' num2str(i)]);
                title(uiAxesComponent, '');
                % Load and display the matched image
                matchImage = imread(fullfile('./vege-train/', app.classNames{index(i)}, originalImageName));
                imshow(matchImage, 'Parent', uiAxesComponent );
                title(uiAxesComponent, app.classNames{index(i)});
                xlabel(uiAxesComponent, sprintf('%.2f%%', matchPercentage), 'FontWeight', 'bold');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Title')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Title')
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Title')
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = 0;
            app.UIAxes.YTick = 0;
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RetriveSimiliarImageButton = uibutton(app.UIFigure, 'push');
            app.RetriveSimiliarImageButton.ButtonPushedFcn = createCallbackFcn(app, @RetriveSimiliarImageButtonPushed, true);
            app.RetriveSimiliarImageButton.Text = 'Retrive Similiar Image';
            app.TOP10SIMILIARIMAGESLabel = uilabel(app.UIFigure);
            app.TOP10SIMILIARIMAGESLabel.Text = 'TOP 10 SIMILIAR IMAGES';
            app.QUERYIMAGELabel = uilabel(app.UIFigure);
            app.QUERYIMAGELabel.Text = 'QUERY IMAGE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end